classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        mCarregarRedeNeuronal          matlab.ui.container.Menu
        mRelatorio                     matlab.ui.container.Menu
        mSair                          matlab.ui.container.Menu
        rightPanel                     matlab.ui.container.Panel
        ProcurarDatasetButton          matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        CriareTreinarRedeNeuronalButton  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        leftPanel                      matlab.ui.container.Panel
        ConfiguraodaRedeNeuronalPanel  matlab.ui.container.Panel
        valSegTest                     matlab.ui.control.NumericEditField
        TestEditFieldLabel             matlab.ui.control.Label
        valSegVal                      matlab.ui.control.NumericEditField
        ValidationEditFieldLabel       matlab.ui.control.Label
        valSegTrain                    matlab.ui.control.NumericEditField
        TrainEditFieldLabel            matlab.ui.control.Label
        nNeuronios_4                   matlab.ui.control.Label
        fTreino                        matlab.ui.control.DropDown
        DropDown_3Label                matlab.ui.control.Label
        nNeuronios_3                   matlab.ui.control.Label
        fAtivacao2                     matlab.ui.control.DropDown
        DropDown_2Label                matlab.ui.control.Label
        fAtivacao1                     matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        nNeuronios_2                   matlab.ui.control.Label
        sliderNeuronios                matlab.ui.control.Slider
        nNeuronios                     matlab.ui.control.Label
        sliderCamadas                  matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        nCamadas                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sliderCamadas
        function sliderCamadasValueChanged(app, event)
            value = round(app.sliderCamadas.Value);
            app.sliderCamadas.Value=round(value);
        end

        % Value changed function: sliderNeuronios
        function sliderNeuroniosValueChanged(app, event)
            value = round(app.sliderNeuronios.Value);
            app.sliderNeuronios.Value=round(value);
        end

        % Button pushed function: CriareTreinarRedeNeuronalButton
        function CriareTreinarRedeNeuronalButtonPushed(app, event)
            if(app.valSegTest.Value + app.valSegVal.Value + app.valSegTrain.Value > 1)
                errordlg("Os valores da segmentação somados não podem ser maiores que 1.0","Erro Rácios Segmentação")
                return;
            end

            if(app.ProcurarDatasetButton.Text == "Procurar Dataset")
                errordlg("Escolha Procurar Dataset através do botão correspondente!", "Erro")  
                return;
            end

            if(app.ProcurarDatasetButton.Text == "Train.csv")
                errordlg("Ficheiro csv inválido: contém valores não numéricos!")
                app.ProcurarDatasetButton.Text = "Procurar Dataset";
                return;
            end

            criar_treinar_rede_neuronal_app(append('Dataset1 - Hepatitis/',app.ProcurarDatasetButton.Text), app.sliderCamadas.Value, ...
                app.sliderNeuronios.Value, app.fAtivacao1.Value, app.fAtivacao2.Value, app.fTreino.Value, app.valSegTrain.Value, ...
                app.valSegVal.Value, app.valSegTest.Value)

            uialert(app.UIFigure,"Rede criada com sucesso com nome: app_net.mat","Sucesso", "Icon","success");

            app.ProcurarDatasetButton.Text = "Procurar Dataset";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if(value == "Definir valores de um caso")
                % Open popup dialog with labels and inputs
                prompt = {'Age:', 'Sex:', 'ALB:', 'ALP:', 'ALT:', 'AST:', 'BIL:', 'CHE:', 'CHOL:', 'CREA:', 'GGT:', 'PROT:'};
                dlgtitle = 'Valores dos Atributos';
                dims = [1 50];
                definput = {'', '', '', '', '', '', '', '', '', '', '', ''};
                userInputs = inputdlg(prompt,dlgtitle,dims,definput);
                
                if ~isempty(userInputs) % User clicked OK
                    % Save user inputs into variables
                    var1 = userInputs{1};
                    var2 = userInputs{2};
                    var3 = userInputs{3};
                    var4 = userInputs{4};
                    var5 = userInputs{5};
                    var6 = userInputs{6};
                    var7 = userInputs{7};
                    var8 = userInputs{8};
                    var9 = userInputs{9};
                    var10 = userInputs{10};
                    var11 = userInputs{11};
                    var12 = userInputs{12};
                    
                    % Use the variables as needed
                    matFiles = dir('*.mat'); % Get all .mat files in the directory
                    matFileNames = {matFiles.name}; % Extract file names
                    [selection,ok] = listdlg('PromptString','Selecione uma rede da lista',...
                        'SelectionMode','single',...
                        'ListString',matFileNames);
                    if ok
                        netName = matFileNames{selection};
                            
                        predicted_target = simular_rede_neuronal_app_caso_unico(var1,var2,var3,var4,var5, ...
                            var6,var7,var8,var9,var10,var11,var12,netName);

                        str_predicted_target = '';
                        switch(predicted_target)
                            case 0
                                str_predicted_target = 'Blood Donor';
                            case 1
                                str_predicted_target = 'suspect Blood Donor';
                            case 2
                                str_predicted_target = 'Hepatitis';
                            case 3
                                str_predicted_target = 'Fibrosis';    
                            case 4
                                str_predicted_target = 'Cirrhosis';
                             
                        end

                        uialert(app.UIFigure,["Caso único simulado pela rede neuronal com sucesso!", strcat("Resultado: ", num2str(predicted_target), "-", str_predicted_target)],"Sucesso", "Icon","success");
                        app.Switch.Value = "Selecionar dataset";
                    end
                end
            end
        end

        % Button pushed function: ProcurarDatasetButton
        function ProcurarDatasetButtonPushed(app, event)
            value = app.Switch.Value;

            if(value == "Selecionar dataset" && app.ProcurarDatasetButton.Text == "Procurar Dataset")
                [file,path] = uigetfile('*.csv');
                if isequal(file,0) || isequal(path,0)
                    %   User canceled the file selection
                    return;
                else
                    uialert(app.UIFigure,"Dataset carregado com sucesso!","Sucesso", "Icon","success");
    
                    app.ProcurarDatasetButton.Text = file;
                end
            elseif(app.ProcurarDatasetButton.Text ~= "Procurar Dataset")
                if(isfile("app_net_treinada.mat"))
                    accuracy = simular_rede_neuronal_app(append('Dataset1 - Hepatitis/',app.ProcurarDatasetButton.Text), "app_net.mat");
                    
                    uialert(app.UIFigure,["Rede Neuronal simulada com sucesso!", strcat("Precisão: ",num2str(accuracy), '%')],"Sucesso", "Icon","success");
                    
                    app.ProcurarDatasetButton.Text = "Procurar Dataset";
                else
                    errordlg("Crie/Treine Rede Neuronal primeiro!", "Erro")
                end
            else
                errordlg("Escolha 'Selecionar dataset' no switch acima!", "Erro")
            end
        end

        % Menu selected function: mCarregarRedeNeuronal
        function mCarregarRedeNeuronalMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
                if isequal(file,0) || isequal(path,0)
                    %   User canceled the file selection
                    return;
                elseif(app.ProcurarDatasetButton.Text == "Procurar Dataset")
                    errordlg("Escolha Procurar Dataset através do botão correspondente!", "Erro")   
                else
                    
                    % CARREGOU REDE PELO MENU E AGORA EXECUTAR NO CSV
                    % SELECIONADO
                    accuracy = simular_rede_neuronal_app(strcat('Dataset1 - Hepatitis/',app.ProcurarDatasetButton.Text), file);

                    uialert(app.UIFigure,["Dataset simulado pela rede neuronal com sucesso!", strcat("Precisão: ",num2str(accuracy), '%')],"Sucesso", "Icon","success");
                
                    app.ProcurarDatasetButton.Text = "Procurar Dataset";
                end
        end

        % Callback function
        function TreinarRedeNeuronalButtonPushed(app, event)
            if(app.ProcurarDatasetButton.Text == "Procurar Dataset")
                errordlg("Escolha Procurar Dataset através do botão correspondente!", "Erro")
                return;
            end

            if(isfile("app_net.mat"))
                treinar_rede_neuronal_app(strcat("Dataset1 - Hepatitis/", app.ProcurarDatasetButton.Text));
                uialert(app.UIFigure,"Rede Neuronal treinada com sucesso e guardada com o nome: net_app_treinada.mat","Sucesso", "Icon","success");
                app.ProcurarDatasetButton.Text = "Procurar Dataset";
            else
                errordlg("Crie uma rede neuronal primeiro!", "Erro")
            end
        end

        % Menu selected function: mRelatorio
        function mRelatorioMenuSelected(app, event)
            open Relatório.docx
            open Relatório.pdf
        end

        % Menu selected function: mSair
        function mSairMenuSelected(app, event)
            SN = questdlg("Tem a certeza que pretende sair?","SAIR","Sim","Não","Não");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.mCarregarRedeNeuronal = uimenu(app.Menu);
            app.mCarregarRedeNeuronal.MenuSelectedFcn = createCallbackFcn(app, @mCarregarRedeNeuronalMenuSelected, true);
            app.mCarregarRedeNeuronal.Text = 'Carregar Rede Neuronal';
            app.mRelatorio = uimenu(app.Menu);
            app.mRelatorio.MenuSelectedFcn = createCallbackFcn(app, @mRelatorioMenuSelected, true);
            app.mRelatorio.Text = 'Relatório';
            app.mSair = uimenu(app.Menu);
            app.mSair.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSair.Text = 'Sair';
            app.leftPanel = uipanel(app.UIFigure);
            app.ConfiguraodaRedeNeuronalPanel = uipanel(app.leftPanel);
            app.ConfiguraodaRedeNeuronalPanel.Title = 'Configuração da Rede Neuronal';
            app.nCamadas = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.nCamadas.Text = 'Número de Camadas';
            app.Label = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.Label.Text = '';
            app.sliderCamadas = uislider(app.ConfiguraodaRedeNeuronalPanel);
            app.sliderCamadas.Limits = [1 5];
            app.sliderCamadas.MajorTicks = [1 2 3 4 5];
            app.sliderCamadas.ValueChangedFcn = createCallbackFcn(app, @sliderCamadasValueChanged, true);
            app.sliderCamadas.MinorTicks = [];
            app.sliderCamadas.Value = 1;
            app.nNeuronios = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.nNeuronios.Text = 'Número de Neurónios p/Camada';
            app.sliderNeuronios = uislider(app.ConfiguraodaRedeNeuronalPanel);
            app.sliderNeuronios.Limits = [1 20];
            app.sliderNeuronios.MajorTicks = [1 5 10 15 20];
            app.sliderNeuronios.ValueChangedFcn = createCallbackFcn(app, @sliderNeuroniosValueChanged, true);
            app.sliderNeuronios.MinorTicks = [2 3 4 6 7 8 9 11 12 13 14 16 17 18 19];
            app.sliderNeuronios.Value = 1;
            app.nNeuronios_2 = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.nNeuronios_2.Text = 'Funções de Ativação';
            app.DropDownLabel = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.DropDownLabel.Text = '';
            app.fAtivacao1 = uidropdown(app.ConfiguraodaRedeNeuronalPanel);
            app.fAtivacao1.Items = {'tansig', 'purelin', 'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tribas'};
            app.fAtivacao1.Value = 'tansig';
            app.DropDown_2Label = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.DropDown_2Label.Text = '';
            app.fAtivacao2 = uidropdown(app.ConfiguraodaRedeNeuronalPanel);
            app.fAtivacao2.Items = {'tansig', 'purelin', 'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tribas'};
            app.fAtivacao2.Value = 'purelin';
            app.nNeuronios_3 = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.nNeuronios_3.Text = 'Função de Treino';
            app.DropDown_3Label = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.DropDown_3Label.Text = '';
            app.fTreino = uidropdown(app.ConfiguraodaRedeNeuronalPanel);
            app.fTreino.Items = {'trainlm', 'trainbfg', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
            app.fTreino.Value = 'trainlm';
            app.nNeuronios_4 = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.nNeuronios_4.Text = 'Rácios de Segmentação';
            app.TrainEditFieldLabel = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.TrainEditFieldLabel.Text = 'Train';
            app.valSegTrain = uieditfield(app.ConfiguraodaRedeNeuronalPanel, 'numeric');
            app.valSegTrain.Value = 0.7;
            app.ValidationEditFieldLabel = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.ValidationEditFieldLabel.Text = 'Validation';
            app.valSegVal = uieditfield(app.ConfiguraodaRedeNeuronalPanel, 'numeric');
            app.valSegVal.Value = 0.15;
            app.TestEditFieldLabel = uilabel(app.ConfiguraodaRedeNeuronalPanel);
            app.TestEditFieldLabel.Text = 'Test';
            app.valSegTest = uieditfield(app.ConfiguraodaRedeNeuronalPanel, 'numeric');
            app.valSegTest.Value = 0.15;
            app.rightPanel = uipanel(app.UIFigure);
            app.Image = uiimage(app.rightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'neural-network.png');
            app.CriareTreinarRedeNeuronalButton = uibutton(app.rightPanel, 'push');
            app.CriareTreinarRedeNeuronalButton.ButtonPushedFcn = createCallbackFcn(app, @CriareTreinarRedeNeuronalButtonPushed, true);
            app.CriareTreinarRedeNeuronalButton.Text = 'Criar e Treinar Rede Neuronal';
            app.Switch = uiswitch(app.rightPanel, 'slider');
            app.Switch.Items = {'Selecionar dataset', 'Definir valores de um caso'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Selecionar dataset';
            app.ProcurarDatasetButton = uibutton(app.rightPanel, 'push');
            app.ProcurarDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarDatasetButtonPushed, true);
            app.ProcurarDatasetButton.Text = 'Procurar Dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end